classdef Flow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        GenerateMeshButton         matlab.ui.control.Button
        NumPointsSpinner           matlab.ui.control.Spinner
        NumPointsSpinnerLabel      matlab.ui.control.Label
        PointsSpinner              matlab.ui.control.Spinner
        PointsSpinnerLabel         matlab.ui.control.Label
        PointsLocationSlider       matlab.ui.control.Slider
        PointsLocationSliderLabel  matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XLine % X value of line points
        YLine % Y value of line points
    end
    
    methods (Access = private)
        
        function plotLine(app)
            x = linspace(0,3,100);
            y = interp1(app.XLine, app.YLine, x, 'spline');
            plot(app.UIAxes,x,y,'-','SeriesIndex',1, 'LineWidth', 2)
            hold(app.UIAxes, 'on')
            scatter(app.UIAxes, app.XLine, app.YLine, 30, 'filled', 'o', 'SeriesIndex', 1)
            scatter(app.UIAxes, app.XLine(app.PointsSpinner.Value), app.YLine(app.PointsSpinner.Value), 30, 'filled', 'o', 'SeriesIndex', 2)
            hold(app.UIAxes, 'off')
            xlim(app.UIAxes, [0 3])
            ylim(app.UIAxes, [0 1])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XLine = [0 3];
            app.YLine = [0.25 0.5];
            app.PointsSpinner.Value = 1;
            app.NumPointsSpinner.Value = 0;
            app.PointsLocationSlider.Value = app.YLine(1);
            app.plotLine();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: PointsSpinner
        function PointsSpinnerValueChanged(app, event)
            value = app.PointsSpinner.Value;
            app.PointsSpinner.Limits = [1 app.NumPointsSpinner.Value+2];
            app.PointsLocationSlider.Value = app.YLine(value); 
            app.plotLine();

        end

        % Value changed function: NumPointsSpinner
        function NumPointsSpinnerValueChanged(app, event)
            value = app.NumPointsSpinner.Value;
            nXLine = linspace(0, 3.0, value+2);
            nYLine = interp1(app.XLine, app.YLine, nXLine, 'spline');
            app.XLine = nXLine;
            app.YLine = nYLine;
            app.plotLine();
        end

        % Value changed function: PointsLocationSlider
        function PointsLocationSliderValueChanged(app, event)
            value = app.PointsLocationSlider.Value;
            app.YLine(app.PointsSpinner.Value) = value;
            app.plotLine();
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)
            
            % Reset axis
            delete(allchild(app.Panel))

            % Define parameter:
            Uinf = 1.0;
            Scaling = Uinf * app.YLine(1);

            % Define the mesh of the line
            XLine = linspace(0,3,100);
            YLine = interp1(app.XLine, app.YLine, XLine, 'spline');
            dl = zeros(7,102);
            for i = 1:99
                dl(:,i) = [2; XLine(i); XLine(i+1); ...
                              YLine(i); YLine(i+1); ...
                              0;1];
            end
            dl(:,100) = [2; XLine(end); 3.0; YLine(end); 0.0; 0; 1];
            dl(:,101) = [2; 3.0; 0.0; 0.0; 0.0; 0; 1];
            dl(:,102) = [2; 0.0; XLine(1); 0.0; YLine(1); 0;1];
 
            % Define the model:
            psi = 1;
            syms psi(x,y)      
            pdeeq = laplacian(psi, [x y]) == 0;
            coeffs = pdeCoefficients(pdeeq, psi);
            model = createpde;
            geometryFromEdges(model,dl);
            generateMesh(model);
            specifyCoefficients(model,'m',coeffs.m,'d',coeffs.d,'c',coeffs.c,'a',coeffs.a,'f',coeffs.f);    
            
            % Define boundary condition:
            applyBoundaryCondition(model,'dirichlet','Edge',1:99,'u',@(region,state) 1.0);
            applyBoundaryCondition(model,'dirichlet','Edge',101,'u',@(region,state) 0.0);

            % Solve PDE
            results = solvepde(model);

            % Plot results:
            pdegplot(model,'EdgeLabels','off');
            hold on
            h2 = pdeplot(model,'FlowData',Scaling*[results.YGradients -results.XGradients])
            copyobj(h2.Parent, app.Panel)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Draw the pipe')
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'y (m)')
            app.UIAxes.XLim = [0 3];
            app.UIAxes.XTick = [0 1 2 3];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'};
            app.UIAxes.YTick = [0 1];
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PointsLocationSliderLabel = uilabel(app.UIFigure);
            app.PointsLocationSliderLabel.Text = 'Points Location';
            app.PointsLocationSlider = uislider(app.UIFigure);
            app.PointsLocationSlider.Limits = [0 1];
            app.PointsLocationSlider.ValueChangedFcn = createCallbackFcn(app, @PointsLocationSliderValueChanged, true);
            app.PointsLocationSlider.Value = 0.5;
            app.PointsSpinnerLabel = uilabel(app.UIFigure);
            app.PointsSpinnerLabel.Text = 'Points';
            app.PointsSpinner = uispinner(app.UIFigure);
            app.PointsSpinner.Limits = [1 5];
            app.PointsSpinner.ValueChangedFcn = createCallbackFcn(app, @PointsSpinnerValueChanged, true);
            app.PointsSpinner.Value = 1;
            app.NumPointsSpinnerLabel = uilabel(app.UIFigure);
            app.NumPointsSpinnerLabel.Text = 'NumPoints';
            app.NumPointsSpinner = uispinner(app.UIFigure);
            app.NumPointsSpinner.Limits = [0 100];
            app.NumPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumPointsSpinnerValueChanged, true);
            app.GenerateMeshButton = uibutton(app.UIFigure, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end